classdef ForwardKinematics_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForwardKinematics_UIFigure  matlab.ui.Figure
        Label_3                     matlab.ui.control.Label
        th1_                        matlab.ui.control.NumericEditField
        Label_4                     matlab.ui.control.Label
        th3_                        matlab.ui.control.NumericEditField
        Label_5                     matlab.ui.control.Label
        th5_                        matlab.ui.control.NumericEditField
        Label_6                     matlab.ui.control.Label
        th6_                        matlab.ui.control.NumericEditField
        Label_7                     matlab.ui.control.Label
        th2_                        matlab.ui.control.NumericEditField
        Button_                     matlab.ui.control.Button
        Label_8                     matlab.ui.control.Label
        alpha_                      matlab.ui.control.NumericEditField
        ZEditFieldLabel_2           matlab.ui.control.Label
        Z_                          matlab.ui.control.NumericEditField
        YEditFieldLabel_2           matlab.ui.control.Label
        Y_                          matlab.ui.control.NumericEditField
        XEditFieldLabel_2           matlab.ui.control.Label
        X_                          matlab.ui.control.NumericEditField
        Label_9                     matlab.ui.control.Label
        beta_                       matlab.ui.control.NumericEditField
        HomeButton                  matlab.ui.control.Button
        ConectButton                matlab.ui.control.Button
        SerialPortDropDownLabel     matlab.ui.control.Label
        SerialPortDropDown          matlab.ui.control.DropDown
        SendButton                  matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        VelocidadSliderLabel        matlab.ui.control.Label
        VelocidadSlider             matlab.ui.control.Slider
        simulate                    matlab.ui.control.Button
        A1Table                     matlab.ui.control.Table
        ShowMatrixesButton          matlab.ui.control.Button
        A2Table                     matlab.ui.control.Table
        A3Table                     matlab.ui.control.Table
        A4Table                     matlab.ui.control.Table
        A5Table                     matlab.ui.control.Table
        ClearButton                 matlab.ui.control.Button
        ForwardKinematicsLabel      matlab.ui.control.Label
        RealRoboticArmControlLabel  matlab.ui.control.Label
        ONLabel                     matlab.ui.control.Label
        A6Table                     matlab.ui.control.Table
    end


    properties (Access = public)
        Property % Description
        portName; %nombre del purto com
        port_melfa; %(obj) puerto del melfa
        A1;
        A2;
        A3;
        A4;
        A5;
        T1;
        
       
    end


    methods (Access = private)

        % Button pushed function: Button_
        function Button_Pushed(app, event)
            th1=app.th1_.Value;
            th2=app.th3_.Value;
            th3=app.th5_.Value;
            th5=app.th6_.Value;
            th6=app.th2_.Value;
            
            [x,y,z,alpha,beta]=forwardken(th1,th2,th3,th5,th6);
            
            
            app.X_.Value=x;
            app.Y_.Value=y;
            app.Z_.Value=z;
            app.alpha_.Value=alpha;
            app.beta_.Value=beta;
            
            [J,A_1,A_2,A_3,A_4,A_5] = JacobianMatrixMelfa(th1,th2,th3,th5,th6)
            
           
            app.A1=A_1;
            app.A2=A_2;
            app.A3=A_3;
            app.A4=A_4;
            app.A5=A_5;
            
            th1 = th1*pi/180;
            th2 = th2*pi/180;
            th3 = th3*pi/180;
            th5 = th5*pi/180;
            th6 = th6*pi/180;
            
            T = [sin(th6)*sin(th1)-cos(th2)*cos(th3)*cos(th5)*cos(th6)*cos(th1) + cos(th2)*cos(th6)*cos(th1)*sin(th3)*sin(th5)+cos(th3)*cos(th6)*cos(th1)*sin(th2)*sin(th5)+cos(th5)*cos(th6)*cos(th1)*sin(th2)*sin(th3) cos(th6)*sin(th1)+cos(th2)*cos(th3)*cos(th5)*cos(th1)*sin(th6)-cos(th2)*cos(th1)*sin(th3)*sin(th5)*sin(th6)-cos(th3)*cos(th1)*sin(th2)*sin(th5)*sin(th6)-cos(th5)*cos(th1)*sin(th2)*sin(th3)*sin(th6)  sin(th2 + th3 + th5 - th1)/2+sin(th2 + th3 + th5 + th1)/2 2*cos(th1)*(36*sin(th2 + th3 + th5)+80*sin(th2 + th3)+125*sin(th2));
     cos(th2)*cos(th6)*sin(th3)*sin(th5)*sin(th1)-cos(th2)*cos(th3)*cos(th5)*cos(th6)*sin(th1) - cos(th1)*sin(th6)+cos(th3)*cos(th6)*sin(th2)*sin(th5)*sin(th1) + cos(th5)*cos(th6)*sin(th2)*sin(th3)*sin(th1) cos(th2)*cos(th3)*cos(th5)*sin(th6)*sin(th1) - cos(th6)*cos(th1) - cos(th2)*sin(th3)*sin(th5)*sin(th6)*sin(th1)-cos(th3)*sin(th2)*sin(th5)*sin(th6)*sin(th1)-cos(th5)*sin(th2)*sin(th3)*sin(th6)*sin(th1) cos(th2 + th3 + th5 - th1)/2-cos(th2 + th3 + th5 + th1)/2 2*sin(th1)*(36*sin(th2 + th3 + th5)+80*sin(th2 + th3)+125*sin(th2));
                                                                                                                                                                                        sin(th2 + th3 + th5 - th6)/2 + sin(th2 + th3 + th5 + th6)/2,                                                                                                                                                                                        cos(th2 + th3 + th5 + th6)/2 - cos(th2 + th3 + th5 - th6)/2,                                                     cos(th2 + th3 + th5),         72*cos(th2 + th3 + th5) + 160*cos(th2 + th3) + 250*cos(th2) + 300;
                                                                                                                                                                                                                                            0,                                                                                                                                                                                                                                                                          0,                                                                                 0,                                                                                           1];
                                                                                                                                                                                                                                        
             app.T1=T;                                                                                                                                                                                                                               
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            home_x=-44.54;
            home_y=-212.11;
            home_z=430.09;
            home_alpha=173.86;
            home_beta=179.29;
           
            %home (puerto,'1;1;EXECP50= (-44.54,-212.11,430.09,173.86,179.29,0.00)(6,0)');
            
            inicioP1 = '1;1;EXECP1=(';
            fin = ',0.00)(6,0)';

            comando_joints = [inicioP1,num2str(home_x),',',num2str(home_y),',',num2str(home_z),',',num2str(home_alpha),',',num2str(home_beta),fin];
            disp('moviendo a la configuración ingresada')
            disp(comando_joints)
            fprintf (app.port_melfa, comando_joints);
            fprintf (app.port_melfa, 13);
            
            fprintf (app.port_melfa, '1;1;EXECMOV P1');%se mueve a la posiscion j1
            fprintf (app.port_melfa, 13);
            disp('Home')
        end

        % Button pushed function: ConectButton
        function ConectButtonPushed2(app, event)
                if(app.ConectButton.Text == ' Conect  ')                
                %quiere decir que aun no está conectado... CONECTAR
                disp('Conectando a puerto serial...')
                app.portName = app.SerialPortDropDown.Value;
                app.portName = char(app.portName);
                disp(app.portName)
                
                app.port_melfa = serial(app.portName);
                disp(app.port_melfa);
                
                %configurando puerto
                app.port_melfa.BaudRate = 9600;
                app.port_melfa.Terminator = 'CR';
                app.port_melfa.DataBits = 8;
                app.port_melfa.Parity = 'none';
                app.port_melfa.StopBits = 1;
                
                fopen(app.port_melfa)%abriendo puerto
                
                disp(app.port_melfa)
                fprintf (app.port_melfa, '1;1;CNTLON'); %inicia soft-control.. creo
                fprintf (app.port_melfa, 13);
                
                fprintf (app.port_melfa, '1;1;SRVON'); %enciende el servo (debe sonar)
                fprintf (app.port_melfa, 13);
                
                app.ConectButton.Text = 'Disconect';
                app.Lamp.Color ='green'
                elseif(app.ConectButton.Text == 'Disconect')
                
                %quiere decir que aun ya estaba conectado... desconectar
                disp('Desconectando de puerto serial...')
                
                fprintf (app.port_melfa, '1;1;SRVOFF'); %enciende el servo (debe sonar)
                fprintf (app.port_melfa, 13);
                
                fclose(app.port_melfa)
                disp(app.port_melfa)
                disp('puerto del melfa cerrado')
                app.ConectButton.Text = ' Conect  ';
                
                app.Lamp.Color ='red'
            end
                
                
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
             
            equis=app.X_.Value;
            ye=app.Y_.Value;
            zeta=app.Z_.Value;
            alfa=app.alpha_.Value;
            beta=app.beta_.Value;
            
            
            inicioP1 = '1;1;EXECP1=(';
            fin = ',0.00)(6,0)';

            comando_joints = [inicioP1,num2str(equis),',',num2str(ye),',',num2str(zeta),',',num2str(alfa),',',num2str(beta),fin];
            disp('moviendo a la configuración ingresada')
            disp(comando_joints)
            fprintf (app.port_melfa, comando_joints);
            fprintf (app.port_melfa, 13);
            
            fprintf (app.port_melfa, '1;1;EXECMOV P1');%se mueve a la posiscion j1
            fprintf (app.port_melfa, 13);
            disp('P1')
        end

        % Value changed function: VelocidadSlider
        function VelocidadSliderValueChanged(app, event)
            value = app.VelocidadSlider.Value;
            value=round(value,0);
            vel_=num2str(value);
            iniciovel= '1;1;OVRD=';
            velocidad=strcat(iniciovel,vel_);
            fprintf (app.port_melfa, velocidad);
            fprintf (app.port_melfa, 13);
            disp('la velocidad es = ')
            disp(velocidad)
            
        end

        % Button pushed function: simulate
        function simulateButtonPushed(app, event)
            th1=app.th1_.Value;
            th2=app.th3_.Value;
            th3=app.th5_.Value;
            th5=app.th6_.Value;
            th6=app.th2_.Value;
            openRobotSimulation(th1,th2,th3,th5,th6);
            
            %t1 = [0,0,0,0,0];
            %t2 = [th1,th2,th3,th5,th6];
            %pruebaRobot(t1,t2);
        end

        % Button pushed function: ShowMatrixesButton
        function ShowMatrixesButtonPushed(app, event)
            
            %primera_matrix
            A1_=round(app.A1,3);
            
               Ai1 = [A1_(1,1),A1_(1,2),A1_(1,3),A1_(1,4);
                      A1_(2,1),A1_(2,2),A1_(2,3),A1_(2,4);
                      A1_(3,1),A1_(3,2),A1_(3,3),A1_(3,4);
                      A1_(4,1),A1_(4,2),A1_(4,3),A1_(4,4);];
              
                     
            Ax = num2cell(Ai1);
            set(app.A1Table, 'Data', Ax)
            
            %Segunda_matrix
            A2_=round(app.A2,3);
            
             Ai2 = [A2_(1,1),A2_(1,2),A2_(1,3),A2_(1,4);
                     A2_(2,1),A2_(2,2),A2_(2,3),A2_(2,4);
                     A2_(3,1),A2_(3,2),A2_(3,3),A2_(3,4);
                     A2_(4,1),A2_(4,2),A2_(4,3),A2_(4,4);];
            
                     
            A2x = num2cell(Ai2);
            set(app.A2Table, 'Data', A2x)
            
            %Tercera_matrix
            A3_=round(app.A3,3);
            
                  Ai3 = [A3_(1,1),A3_(1,2),A3_(1,3),A3_(1,4);
                     A3_(2,1),A3_(2,2),A3_(2,3),A3_(2,4);
                     A3_(3,1),A3_(3,2),A3_(3,3),A3_(3,4);
                     A3_(4,1),A3_(4,2),A3_(4,3),A3_(4,4);];
                     
            A3x = num2cell(Ai3);
            set(app.A3Table, 'Data', A3x)
            
            
            %Cuarta_matrix
            A4_=round(app.A4,3);
            
             Ai4 = [A4_(1,1),A4_(1,2),A4_(1,3),A4_(1,4);
                    A4_(2,1),A4_(2,2),A4_(2,3),A4_(2,4);
                    A4_(3,1),A4_(3,2),A4_(3,3),A4_(3,4);
                    A4_(4,1),A4_(4,2),A4_(4,3),A4_(4,4);];
                     
            A4x = num2cell(Ai4);
            set(app.A4Table, 'Data', A4x)
            
             %Quinto_matrix
            A5_=round(app.A5,3);
            
             Ai5 = [A5_(1,1),A5_(1,2),A5_(1,3),A5_(1,4);
                    A5_(2,1),A5_(2,2),A5_(2,3),A5_(2,4);
                    A5_(3,1),A5_(3,2),A5_(3,3),A5_(3,4);
                    A5_(4,1),A5_(4,2),A5_(4,3),A5_(4,4);];
                     
            A5x = num2cell(Ai5);
            set(app.A5Table, 'Data', A5x)


             %Matriz_T
            A6_=round(app.T1,3);
            
             Ai6 = [A6_(1,1),A6_(1,2),A6_(1,3),A6_(1,4);
                    A6_(2,1),A6_(2,2),A6_(2,3),A6_(2,4);
                    A6_(3,1),A6_(3,2),A6_(3,3),A6_(3,4);
                    A6_(4,1),A6_(4,2),A6_(4,3),A6_(4,4);];
                     
            A6x = num2cell(Ai6);
            set(app.A6Table, 'Data', A6x)  
            
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            A_clear=[0,0,0,0;
                     0,0,0,0;
                     0,0,0,0;
                     0,0,0,0;];
                 
             %primera_matrix
            A1_=round(A_clear,3);
            
               Ai1 = [A1_(1,1),A1_(1,2),A1_(1,3),A1_(1,4);
                      A1_(2,1),A1_(2,2),A1_(2,3),A1_(2,4);
                      A1_(3,1),A1_(3,2),A1_(3,3),A1_(3,4);
                      A1_(4,1),A1_(4,2),A1_(4,3),A1_(4,4);];
              
                     
            Ax = num2cell(Ai1);
            set(app.A1Table, 'Data', Ax)
            
            %Segunda_matrix
            A2_=round(A_clear,3);
            
             Ai2 = [A2_(1,1),A2_(1,2),A2_(1,3),A2_(1,4);
                     A2_(2,1),A2_(2,2),A2_(2,3),A2_(2,4);
                     A2_(3,1),A2_(3,2),A2_(3,3),A2_(3,4);
                     A2_(4,1),A2_(4,2),A2_(4,3),A2_(4,4);];
            
                     
            A2x = num2cell(Ai2);
            set(app.A2Table, 'Data', A2x)
            
            %Tercera_matrix
            A3_=round(A_clear,3);
            
                  Ai3 = [A3_(1,1),A3_(1,2),A3_(1,3),A3_(1,4);
                     A3_(2,1),A3_(2,2),A3_(2,3),A3_(2,4);
                     A3_(3,1),A3_(3,2),A3_(3,3),A3_(3,4);
                     A3_(4,1),A3_(4,2),A3_(4,3),A3_(4,4);];
                     
            A3x = num2cell(Ai3);
            set(app.A3Table, 'Data', A3x)
            
            
            %Cuarta_matrix
            A4_=round(A_clear,3);
            
             Ai4 = [A4_(1,1),A4_(1,2),A4_(1,3),A4_(1,4);
                    A4_(2,1),A4_(2,2),A4_(2,3),A4_(2,4);
                    A4_(3,1),A4_(3,2),A4_(3,3),A4_(3,4);
                    A4_(4,1),A4_(4,2),A4_(4,3),A4_(4,4);];
                     
            A4x = num2cell(Ai4);
            set(app.A4Table, 'Data', A4x)
            
             %Quinto_matrix
            A5_=round(A_clear,3);
            
             Ai5 = [A5_(1,1),A5_(1,2),A5_(1,3),A5_(1,4);
                    A5_(2,1),A5_(2,2),A5_(2,3),A5_(2,4);
                    A5_(3,1),A5_(3,2),A5_(3,3),A5_(3,4);
                    A5_(4,1),A5_(4,2),A5_(4,3),A5_(4,4);];
                     
            A5x = num2cell(Ai5);
            set(app.A5Table, 'Data', A5x)  
            
            
              %Matriz_T
            A6_=round(A_clear,3);
            
             Ai6 = [A6_(1,1),A6_(1,2),A6_(1,3),A6_(1,4);
                    A6_(2,1),A6_(2,2),A6_(2,3),A6_(2,4);
                    A6_(3,1),A6_(3,2),A6_(3,3),A6_(3,4);
                    A6_(4,1),A6_(4,2),A6_(4,3),A6_(4,4);];
                     
            A6x = num2cell(Ai6);
            set(app.A6Table, 'Data', A6x)  
            
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForwardKinematics_UIFigure = uifigure;
            app.ForwardKinematics_UIFigure.Name = 'ForwardKinematics';
            app.Label_3 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_3.Text = 'θ1';
            app.th1_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.th1_.Limits = [-150 150];
            app.th1_.ValueDisplayFormat = '%.2f';
            app.th1_.Value = 10.54;
            app.Label_4 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_4.Text = 'θ2';
            app.th3_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.th3_.Limits = [-60 120];
            app.th3_.ValueDisplayFormat = '%.2f';
            app.th3_.Value = -2.44;
            app.Label_5 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_5.Text = 'θ3';
            app.th5_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.th5_.Limits = [-110 120];
            app.th5_.ValueDisplayFormat = '%.2f';
            app.th5_.Value = 8.59;
            app.Label_6 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_6.Text = 'θ5';
            app.th6_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.th6_.Limits = [-90 90];
            app.th6_.ValueDisplayFormat = '%.2f';
            app.th6_.Value = 89.99;
            app.Label_7 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_7.Text = 'θ6';
            app.th2_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.th2_.Limits = [-200 200];
            app.th2_.ValueDisplayFormat = '%.2f';
            app.th2_.Value = -0.62;
            app.Button_ = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.Button_.ButtonPushedFcn = createCallbackFcn(app, @Button_Pushed, true);
            app.Button_.Text = 'Compute';
            app.Label_8 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_8.Text = 'α';
            app.alpha_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.alpha_.ValueDisplayFormat = '%.2f';
            app.alpha_.Editable = 'off';
            app.ZEditFieldLabel_2 = uilabel(app.ForwardKinematics_UIFigure);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Z_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.Z_.ValueDisplayFormat = '%.2f';
            app.Z_.Editable = 'off';
            app.YEditFieldLabel_2 = uilabel(app.ForwardKinematics_UIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.Y_.ValueDisplayFormat = '%.2f';
            app.Y_.Editable = 'off';
            app.XEditFieldLabel_2 = uilabel(app.ForwardKinematics_UIFigure);
            app.XEditFieldLabel_2.Text = 'X';
            app.X_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.X_.ValueDisplayFormat = '%.2f';
            app.X_.Editable = 'off';
            app.Label_9 = uilabel(app.ForwardKinematics_UIFigure);
            app.Label_9.Text = ' β';
            app.beta_ = uieditfield(app.ForwardKinematics_UIFigure, 'numeric');
            app.beta_.ValueDisplayFormat = '%.2f';
            app.beta_.Editable = 'off';
            app.HomeButton = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.ConectButton = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.ConectButton.ButtonPushedFcn = createCallbackFcn(app, @ConectButtonPushed2, true);
            app.ConectButton.Text = ' Conect  ';
            app.SerialPortDropDownLabel = uilabel(app.ForwardKinematics_UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.ForwardKinematics_UIFigure);
            app.SerialPortDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9', 'COM10', 'COM11', 'COM12', 'COM13', 'COM14', 'COM15', 'COM16', 'COM17', 'COM18', 'COM19', 'COM20', 'COM21', 'COM22', 'COM23', 'COM24', 'COM25', 'COM26', 'COM27', 'COM28', 'COM29', 'COM30'};
            app.SerialPortDropDown.Value = 'COM1';
            app.SendButton = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.Lamp = uilamp(app.ForwardKinematics_UIFigure);
            app.VelocidadSliderLabel = uilabel(app.ForwardKinematics_UIFigure);
            app.VelocidadSliderLabel.Text = 'Velocidad';
            app.VelocidadSlider = uislider(app.ForwardKinematics_UIFigure);
            app.VelocidadSlider.MajorTicks = [0 20 40 60 80 100];
            app.VelocidadSlider.ValueChangedFcn = createCallbackFcn(app, @VelocidadSliderValueChanged, true);
            app.VelocidadSlider.Value = 15;
            app.simulate = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.simulate.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulate.Text = 'Simulate';
            app.A1Table = uitable(app.ForwardKinematics_UIFigure);
            app.A1Table.ColumnName = {'A1'; ''; ''; ''};
            app.A1Table.RowName = {};
            app.ShowMatrixesButton = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.ShowMatrixesButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMatrixesButtonPushed, true);
            app.ShowMatrixesButton.Text = 'Show Matrixes';
            app.A2Table = uitable(app.ForwardKinematics_UIFigure);
            app.A2Table.ColumnName = {'A2'; ''; ''; ''};
            app.A2Table.RowName = {};
            app.A3Table = uitable(app.ForwardKinematics_UIFigure);
            app.A3Table.ColumnName = {'A3'; ''; ''; ''};
            app.A3Table.RowName = {};
            app.A4Table = uitable(app.ForwardKinematics_UIFigure);
            app.A4Table.ColumnName = {'A4'; ''; ''; ''};
            app.A4Table.RowName = {};
            app.A5Table = uitable(app.ForwardKinematics_UIFigure);
            app.A5Table.ColumnName = {'A5'; ''; ''; ''};
            app.A5Table.RowName = {};
            app.ClearButton = uibutton(app.ForwardKinematics_UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ForwardKinematicsLabel = uilabel(app.ForwardKinematics_UIFigure);
            app.ForwardKinematicsLabel.Text = 'Forward Kinematics';
            app.RealRoboticArmControlLabel = uilabel(app.ForwardKinematics_UIFigure);
            app.RealRoboticArmControlLabel.Text = 'Real Robotic Arm Control';
            app.ONLabel = uilabel(app.ForwardKinematics_UIFigure);
            app.ONLabel.Text = 'ON';
            app.A6Table = uitable(app.ForwardKinematics_UIFigure);
            app.A6Table.ColumnName = {'T'; ''; ''; ''};
            app.A6Table.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ForwardKinematics_

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForwardKinematics_UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForwardKinematics_UIFigure)
        end
    end
end