classdef Inverse_Velocity_ < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Inverse_VelocityUIFigure  matlab.ui.Figure
        Q1Label                   matlab.ui.control.Label
        Q1_                       matlab.ui.control.NumericEditField
        Q2Label                   matlab.ui.control.Label
        Q2_                       matlab.ui.control.NumericEditField
        Q3Label                   matlab.ui.control.Label
        Q3_                       matlab.ui.control.NumericEditField
        Q5Label                   matlab.ui.control.Label
        Q5_                       matlab.ui.control.NumericEditField
        Q6Label                   matlab.ui.control.Label
        Q6_                       matlab.ui.control.NumericEditField
        VxLabel                   matlab.ui.control.Label
        Vx_                       matlab.ui.control.NumericEditField
        VyLabel                   matlab.ui.control.Label
        Vy_                       matlab.ui.control.NumericEditField
        VzLabel                   matlab.ui.control.Label
        Vz_                       matlab.ui.control.NumericEditField
        WxLabel                   matlab.ui.control.Label
        Wx_                       matlab.ui.control.NumericEditField
        WyLabel                   matlab.ui.control.Label
        Wy_                       matlab.ui.control.NumericEditField
        WzLabel                   matlab.ui.control.Label
        Wz_                       matlab.ui.control.NumericEditField
        ComputeButton             matlab.ui.control.Button
        q3_puntoLabel             matlab.ui.control.Label
        q3_punto_                 matlab.ui.control.NumericEditField
        q5_puntoLabel             matlab.ui.control.Label
        q5_punto_                 matlab.ui.control.NumericEditField
        q6_puntoLabel             matlab.ui.control.Label
        q6_punto_                 matlab.ui.control.NumericEditField
        q2_puntoLabel             matlab.ui.control.Label
        q2_punto_                 matlab.ui.control.NumericEditField
        q1_puntoLabel             matlab.ui.control.Label
        q1_punto_                 matlab.ui.control.NumericEditField
        ShowJacobianButton        matlab.ui.control.Button
        A1Table                   matlab.ui.control.Table
        InverseVelocityLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
        Jabocobian;
        prueba;
    end


    methods (Access = private)

        % Callback function: ComputeButton, Q1_
        function ComputeButtonPushed(app, event)
            Q1 =app.Q1_.Value;
            Q2 =app.Q2_.Value;
            Q3 =app.Q3_.Value;
            Q5 =app.Q5_.Value;
            Q6 =app.Q6_.Value;
            
            
            Vx= app.Vx_.Value;
            Vy= app.Vy_.Value;
            Vz= app.Vz_.Value;
            Wx= app.Wx_.Value;
            Wy= app.Wy_.Value;
            Wz=app.Wz_.Value;
            
            
            end_effectorvel=[Vx;Vy;Vz;Wx;Wy;Wz];
            
            [JointVelocity, J]=InverseVelocityMelfa(Q1,Q2,Q3,Q5,Q6,end_effectorvel)
            
            app.q1_punto_.Value = JointVelocity(1);
            app.q2_punto_.Value = JointVelocity(2);
            app.q3_punto_.Value = JointVelocity(3);
            app.q5_punto_.Value = JointVelocity(4);
            app.q6_punto_.Value = JointVelocity(5);
            app.Jabocobian=J
            
        end

        % Button pushed function: ShowJacobianButton
        function ShowJacobianButtonPushed(app, event)
             m=round(app.Jabocobian,4);
            
            Ai = [m(1,1),m(1,2),m(1,3),m(1,4),m(1,5);
                  m(2,1),m(2,2),m(2,3),m(2,4),m(2,5);
                  m(3,1),m(3,2),m(3,3),m(3,4),m(3,5);
                  m(4,1),m(4,2),m(4,3),m(4,4),m(4,5);
                  m(5,1),m(5,2),m(5,3),m(5,4),m(5,5);
                  m(6,1),m(6,2),m(6,3),m(6,4),m(6,5);];
            Ax = num2cell(Ai);
            set(app.A1Table, 'Data', Ax)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Inverse_VelocityUIFigure = uifigure;
            app.Inverse_VelocityUIFigure.Name = 'Inverse_Velocity';
            app.Q1Label = uilabel(app.Inverse_VelocityUIFigure);
            app.Q1Label.Text = 'Q1';
            app.Q1_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Q1_.ValueDisplayFormat = '%.2f';
            app.Q1_.ValueChangedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.Q1_.Value = 76.8;
            app.Q2Label = uilabel(app.Inverse_VelocityUIFigure);
            app.Q2Label.Text = 'Q2';
            app.Q2_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Q2_.ValueDisplayFormat = '%.2f';
            app.Q2_.Value = 14.29;
            app.Q3Label = uilabel(app.Inverse_VelocityUIFigure);
            app.Q3Label.Text = 'Q3';
            app.Q3_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Q3_.ValueDisplayFormat = '%.2f';
            app.Q3_.Value = 701.14;
            app.Q5Label = uilabel(app.Inverse_VelocityUIFigure);
            app.Q5Label.Text = 'Q5';
            app.Q5_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Q5_.ValueDisplayFormat = '%.2f';
            app.Q5_.Value = -1.75;
            app.Q6Label = uilabel(app.Inverse_VelocityUIFigure);
            app.Q6Label.Text = 'Q6';
            app.Q6_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Q6_.ValueDisplayFormat = '%.2f';
            app.Q6_.Value = 96.15;
            app.VxLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.VxLabel.Text = 'Vx';
            app.Vx_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Vx_.ValueDisplayFormat = '%.2f';
            app.Vx_.Value = 76.8;
            app.VyLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.VyLabel.Text = 'Vy';
            app.Vy_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Vy_.ValueDisplayFormat = '%.2f';
            app.Vy_.Value = 14.29;
            app.VzLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.VzLabel.Text = 'Vz';
            app.Vz_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Vz_.ValueDisplayFormat = '%.2f';
            app.Vz_.Value = 701.14;
            app.WxLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.WxLabel.Text = 'Wx';
            app.Wx_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Wx_.ValueDisplayFormat = '%.2f';
            app.Wx_.Value = -1.75;
            app.WyLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.WyLabel.Text = 'Wy';
            app.Wy_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Wy_.ValueDisplayFormat = '%.2f';
            app.Wy_.Value = 96.15;
            app.WzLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.WzLabel.Text = 'Wz';
            app.Wz_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.Wz_.ValueDisplayFormat = '%.2f';
            app.Wz_.Value = 96.15;
            app.ComputeButton = uibutton(app.Inverse_VelocityUIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.q3_puntoLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.q3_puntoLabel.Text = 'q3_punto';
            app.q3_punto_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.q3_punto_.ValueDisplayFormat = '%.2f';
            app.q5_puntoLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.q5_puntoLabel.Text = 'q5_punto';
            app.q5_punto_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.q5_punto_.ValueDisplayFormat = '%.2f';
            app.q6_puntoLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.q6_puntoLabel.Text = 'q6_punto';
            app.q6_punto_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.q6_punto_.ValueDisplayFormat = '%.2f';
            app.q2_puntoLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.q2_puntoLabel.Text = 'q2_punto';
            app.q2_punto_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.q2_punto_.ValueDisplayFormat = '%.2f';
            app.q1_puntoLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.q1_puntoLabel.Text = 'q1_punto';
            app.q1_punto_ = uieditfield(app.Inverse_VelocityUIFigure, 'numeric');
            app.q1_punto_.ValueDisplayFormat = '%.2f';
            app.ShowJacobianButton = uibutton(app.Inverse_VelocityUIFigure, 'push');
            app.ShowJacobianButton.ButtonPushedFcn = createCallbackFcn(app, @ShowJacobianButtonPushed, true);
            app.ShowJacobianButton.Text = 'Show Jacobian';
            app.A1Table = uitable(app.Inverse_VelocityUIFigure);
            app.A1Table.ColumnName = {'J1'; ''; ''; ''; ''};
            app.A1Table.RowName = {};
            app.InverseVelocityLabel = uilabel(app.Inverse_VelocityUIFigure);
            app.InverseVelocityLabel.Text = 'Inverse Velocity';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Inverse_Velocity_

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Inverse_VelocityUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Inverse_VelocityUIFigure)
        end
    end
end