classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Title                        matlab.ui.control.Label
        MenuButtonGroup              matlab.ui.container.ButtonGroup
        FowardKinematicsButton       matlab.ui.control.ToggleButton
        InverseKinematicsButton      matlab.ui.control.ToggleButton
        ForwardVelocityButton        matlab.ui.control.ToggleButton
        InverseVelocityButton        matlab.ui.control.ToggleButton
        VirtualRoboticArmButton      matlab.ui.control.ToggleButton
        ElaboratedbyLabel            matlab.ui.control.Label
        LauraSofiaLucioLabel         matlab.ui.control.Label
        RaquelPayroBucioLabel        matlab.ui.control.Label
        RodrigoChiOzaldeLabel        matlab.ui.control.Label
        UniversidadAnhuacMayabLabel  matlab.ui.control.Label
        StraightTrajectoryButton     matlab.ui.control.ToggleButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: MenuButtonGroup
        function MenuButtonGroupSelectionChanged(app, event)
            selectedButton = app.MenuButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.FowardKinematicsButton
                     ForwardKinematics_;
                    
                case app.InverseKinematicsButton
                     InverseKinematic_;                                     
                case app.ForwardVelocityButton
                     Forward_Velocity;
                case app.InverseVelocityButton
                     Inverse_Velocity_;
                case app.VirtualRoboticArmButton
                    openRobotSimulation(0,0,0,0,0);
                case app.StraightTrajectoryButton;
                     Straigth_Line;
                otherwise
                    
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UIFigure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Mitsubishi Melfa RV_2AJ';
            app.MenuButtonGroup = uibuttongroup(app.UIFigure);
            app.MenuButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MenuButtonGroupSelectionChanged, true);
            app.MenuButtonGroup.Title = 'Menu';
            app.FowardKinematicsButton = uitogglebutton(app.MenuButtonGroup);
            app.FowardKinematicsButton.Text = 'Foward Kinematics';
            app.InverseKinematicsButton = uitogglebutton(app.MenuButtonGroup);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            app.ForwardVelocityButton = uitogglebutton(app.MenuButtonGroup);
            app.ForwardVelocityButton.Text = 'Forward Velocity';
            app.InverseVelocityButton = uitogglebutton(app.MenuButtonGroup);
            app.InverseVelocityButton.Text = 'Inverse Velocity';
            app.VirtualRoboticArmButton = uitogglebutton(app.MenuButtonGroup);
            app.VirtualRoboticArmButton.Text = 'Virtual Robotic Arm';
            app.VirtualRoboticArmButton.Value = true;
            app.ElaboratedbyLabel = uilabel(app.MenuButtonGroup);
            app.ElaboratedbyLabel.Text = 'Elaborated by';
            app.LauraSofiaLucioLabel = uilabel(app.MenuButtonGroup);
            app.LauraSofiaLucioLabel.Text = 'Laura Sofia Lucio';
            app.RaquelPayroBucioLabel = uilabel(app.MenuButtonGroup);
            app.RaquelPayroBucioLabel.Text = 'Raquel Payro Bucio';
            app.RodrigoChiOzaldeLabel = uilabel(app.MenuButtonGroup);
            app.RodrigoChiOzaldeLabel.Text = 'Rodrigo Chi Ozalde';
            app.UniversidadAnhuacMayabLabel = uilabel(app.MenuButtonGroup);
            app.UniversidadAnhuacMayabLabel.Text = 'Universidad Anáhuac Mayab';
            app.StraightTrajectoryButton = uitogglebutton(app.MenuButtonGroup);
            app.StraightTrajectoryButton.Text = 'Straight Trajectory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end