classdef SimulacionRobot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ControlDirectoTab            matlab.ui.container.Tab
        EditField_6Label             matlab.ui.control.Label
        EditField_t1                 matlab.ui.control.NumericEditField
        EditField_7Label             matlab.ui.control.Label
        EditField_t2                 matlab.ui.control.NumericEditField
        EditField_8Label             matlab.ui.control.Label
        EditField_t3                 matlab.ui.control.NumericEditField
        EditField_9Label             matlab.ui.control.Label
        EditField_t5                 matlab.ui.control.NumericEditField
        EditField_10Label            matlab.ui.control.Label
        EditField_t6                 matlab.ui.control.NumericEditField
        MatrizButton                 matlab.ui.control.Button
        MoverButton                  matlab.ui.control.Button
        MatrizDirectaLabel           matlab.ui.control.Label
        MatrizDirectaResultadoLabel  matlab.ui.control.Label
        ControlInversoTab            matlab.ui.container.Tab
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        ZEditField                   matlab.ui.control.NumericEditField
        AEditFieldLabel              matlab.ui.control.Label
        AEditField                   matlab.ui.control.NumericEditField
        BEditFieldLabel              matlab.ui.control.Label
        BEditField                   matlab.ui.control.NumericEditField
        MatrizButton_2               matlab.ui.control.Button
        MoverButton_2                matlab.ui.control.Button
        MatrizInversaLabel           matlab.ui.control.Label
        VelocidadDirectaTab          matlab.ui.container.Tab
        VelEfector1EditField         matlab.ui.control.NumericEditField
        VelEfector1EditField_2       matlab.ui.control.NumericEditField
        VelEfector1EditField_3       matlab.ui.control.NumericEditField
        VelEfector1EditField_4       matlab.ui.control.NumericEditField
        VelEfector1EditField_5       matlab.ui.control.NumericEditField
        VelocidadEfectorLabel        matlab.ui.control.Label
        MatrizJacobianaLabel         matlab.ui.control.Label
        JacobianaLabel               matlab.ui.control.Label
        AnguloVelEditField           matlab.ui.control.NumericEditField
        AnguloVelEditField_2         matlab.ui.control.NumericEditField
        AnguloVelEditField_3         matlab.ui.control.NumericEditField
        AnguloVelEditField_4         matlab.ui.control.NumericEditField
        AnguloVelEditField_5         matlab.ui.control.NumericEditField
        AnguloLabel                  matlab.ui.control.Label
        VelocidadAngularEditField    matlab.ui.control.NumericEditField
        VelocidadAngularEditField_2  matlab.ui.control.NumericEditField
        VelocidadAngularEditField_3  matlab.ui.control.NumericEditField
        VelocidadAngularEditField_4  matlab.ui.control.NumericEditField
        VelocidadAngularEditField_5  matlab.ui.control.NumericEditField
        GenerarMatrizButton          matlab.ui.control.Button
        VelocidadAngularLabel        matlab.ui.control.Label
        VelEfector1EditField_6       matlab.ui.control.NumericEditField
        VLabel                       matlab.ui.control.Label
        WLabel                       matlab.ui.control.Label
        VelocidadInversaTab          matlab.ui.container.Tab
        TrayectoriaTab               matlab.ui.container.Tab
    end


    properties (Access = private)
        Movimiento=0; % cuando es 0 es movimiento con directa cuando 1 indirecta
    end

    properties (Access = public)
        posicionActual=[0 0 0 0 0]; % Description
    end

    methods (Access = public)
    
       
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %    para las matrices de A1 a A6 se guardan los resultados en un string
        function [linea1,linea2,linea3,linea4]=matrix2label(app,p1)
            p1=transpose(p1);
            testa=[p1(1)  p1(2) p1(3) p1(4)];
            testb=[p1(5)  p1(6) p1(7) p1(8)];
            testc=[p1(9)  p1(10) p1(11) p1(12)];
            testd=[p1(13) p1(14) p1(15) p1(16)];
            
            % linea1 = sprintf(' %.4g ',testa);
            linea1 = sprintf(' %.3f ',testa);
            linea2 = sprintf(' %.3f ',testb);
            linea3 = sprintf(' %.3f ',testc);
            linea4 = sprintf(' %.3f ',testd);
        end
       
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load_system('robot_Rva2j');
            vrview('robot_Rva2j#test');
          %  pause(3)
          %  set_param('robot_Rva2j', 'SimulationCommand', 'start');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            %set_param('robot_Rva2j', 'SimulationCommand', 'stop');
        end

        % Button pushed function: MoverButton
        function MoverButtonPushed(app, event)
          
                app.MoverButton.Enable='off';
                app.posicionActual
                t1=[app.EditField_t1.Value app.EditField_t2.Value app.EditField_t3.Value app.EditField_t5.Value app.EditField_t6.Value]
               
                %% si se pone 180 grados y luego 90 deberia restar no sumar arreglar eso
                  
                  t2=[round(t1(1)) round(t1(2)) round(t1(3)) round(t1(4)) round(t1(5))]
                t2
                pruebaRobot(app.posicionActual,t2);
                
                app.posicionActual=t2;
                app.MoverButton.Enable='on';
        
        end

        % Callback function
        function DatosSwitchValueChanged(app, event)
          
        end

        % Callback function
        function GenerarButtonPushed(app, event)
         
         %   if app.Movimiento==0
          %      [T,X,Y,Z,Beta,Alpha] = MELFA_RV_2AJ_FK_v2(app.EditField_t1.Value,app.EditField_t2.Value,app.EditField_t3.Value,app.EditField_t5.Value,app.EditField_t6.Value);
          %      app.XEditField.Value=X;
          %      app.YEditField.Value=Y;
          %      app.ZEditField.Value=Z;
          %      app.AEditField.Value=Alpha;
          %      app.BEditField.Value=Beta;
          %  else
          %      [invken]=inverseken(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.BEditField.Value,app.AEditField.Value)
          %      app.EditField_t1.Value=invken(1);
          %      app.EditField_t2.Value=invken(2);
          %      app.EditField_t3.Value=invken(3);
          %      app.EditField_t5.Value=invken(4);
          %      app.EditField_t6.Value=invken(5);
          %  end
            
        end

        % Callback function
        function PosicionActualButtonPushed(app, event)
            app.RobotGUIfinal.ValorXactual
        end

        % Button pushed function: MatrizButton
        function MatrizButtonPushed(app, event)
                Aa1=Transf_MatrixA(degtorad(app.EditField_t1.Value),300,0,-pi/2)
                Aa2=Transf_MatrixA(degtorad(app.EditField_t2.Value-90),0,250,0)
                Aa3=Transf_MatrixA(degtorad(app.EditField_t3.Value),0,160,0)
                Aa5=Transf_MatrixA(degtorad(app.EditField_t5.Value-90),0,0,-pi/2)
                Aa6=Transf_MatrixA(degtorad(app.EditField_t6.Value),72,0,0)
                [Aat,x,y,z,b,a]=MELFA_RV_2AJ_FK_v2(app.EditField_t1.Value,app.EditField_t2.Value,app.EditField_t3.Value,app.EditField_t5.Value,app.EditField_t6.Value)
                % testa=[num2str(test(1)) num2str(test(2)); num2str(test(4))]
                [a1a,a1b,a1c,a1d]=matrix2label(app,Aa1);
                [a2a,a2b,a2c,a2d]=matrix2label(app,Aa2);
                [a3a,a3b,a3c,a3d]=matrix2label(app,Aa3);
                [a5a,a5b,a5c,a5d]=matrix2label(app,Aa5);
                [a6a,a6b,a6c,a6d]=matrix2label(app,Aa6);
                [ata,atb,atc,atd]=matrix2label(app,Aat);
      
             
                app.MatrizDirectaLabel.Text = {'A1= ',a1a,a1b,a1c,a1d,' ','A2= ',a2a,a2b,a2c,a2d,' ','A3= ',a3a,a3b,a3c,a3d,' ','A5= ',a5a,a5b,a5c,a5d,' ','A6= ',a6a,a6b,a6c,a6d};
                app.MatrizDirectaResultadoLabel.Text={'At= ',ata,atb,atc,atd,' ','X= ',num2str(x),' ','Y= ',num2str(y),' ','Z= ',num2str(z),' ','A=', num2str(a),' ','B= ',num2str(b)};
        end

        % Button pushed function: MatrizButton_2
        function MatrizButton_2Pushed(app, event)
             t=inverseken(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.BEditField.Value,app.AEditField.Value);
               
               
                app.MatrizInversaLabel.Text = {'a=300, b=250, c=160, d=72',' ',['L1=z-d*sin((-',num2str(app.BEditField.Value),'+90)*pi/180)-a'],' ',['L2 = sqrt((',num2str(app.XEditField.Value),')^2 + (',num2str(app.YEditField.Value),')^2) - d*cos((-',num2str(app.AEditField.Value),'+90) * pi/180)'],' ',['theta1 = atan2(',num2str(app.YEditField.Value),',',num2str(app.XEditField.Value),')*180/pi;'],' ',['theta2 = (atan2(L1,L2)*180/pi) + (acosd((b^2 - c^2 +'],' ((L1)^2 + (L2)^2))/(2*(sqrt((L1)^2 + (L2)^2))*b)));',' ','theta3 = -(180 - (acosd((b^2 + c^2 - ((L1)^2 + (L2)^2))/(2*c*b))));',' ',['theta5 = (',num2str(app.AEditField.Value),'-90)+',num2str(t(2)),'+',num2str(t(3)),''],' ',['θ1= ',num2str(t(1))],' ',['θ2= ',num2str(t(2))],' ',['θ3= ',num2str(t(3))],' ',['θ5= ',num2str(t(4))],' ',['θ6= ',num2str(t(5))]};
                
               % app.XEditField_4.Value=(t(1));
                %app.YEditField_4.Value=(t(2));
                %app.ZEditField_4.Value=(t(3));
                %app.AEditField_4.Value=(t(4));
               % app.BEditField_4.Value=(t(5));
        end

        % Button pushed function: MoverButton_2
        function MoverButton_2Pushed(app, event)
                app.MoverButton_2.Enable='off';
                app.posicionActual
               % t1=[app.EditField_t1.Value app.EditField_t2.Value app.EditField_t3.Value app.EditField_t5.Value app.EditField_t6.Value]
               
                  t=inverseken(app.XEditField.Value,app.YEditField.Value,app.ZEditField.Value,app.BEditField.Value,app.AEditField.Value);
                %% si se pone 180 grados y luego 90 deberia restar no sumar arreglar eso
                  
                  t2=[round(t(1)) round(t(2)) round(t(3)) round(t(4)) round(t(5))]
                t2
                pruebaRobot(app.posicionActual,t2);
                
                app.posicionActual=t2;
                app.MoverButton_2.Enable='on';
        end

        % Button pushed function: GenerarMatrizButton
        function GenerarMatrizButtonPushed(app, event)
            velangular=[app.VelocidadAngularEditField.Value;app.VelocidadAngularEditField_2.Value;app.VelocidadAngularEditField_3.Value;app.VelocidadAngularEditField_4.Value;app.VelocidadAngularEditField_5.Value];
            angulos=[app.AnguloVelEditField.Value,app.AnguloVelEditField_2.Value,app.AnguloVelEditField_3.Value,app.AnguloVelEditField_4.Value,app.AnguloVelEditField_5.Value];
            
            [ForwVel,J] =VelDirectaMelfa(angulos(1),angulos(2),angulos(3),angulos(4),angulos(5),velangular)
            
            app.MatrizJacobianaLabel.Text={[num2str(round(J(1,1),3)),' ',num2str(round(J(1,2),3)),' ',num2str(round(J(1,3),3)),' ',num2str(round(J(1,4),3)),' ',num2str(round(J(1,5),3))],' ',[num2str(round(J(2,1),3)),' ',num2str(round(J(2,2),3)),' ',num2str(round(J(2,3),3)),' ',num2str(round(J(2,4),3)),' ',num2str(round(J(2,5),3))],' ',[num2str(round(J(3,1),3)),' ',num2str(round(J(3,2),3)),' ',num2str(round(J(3,3),3)),' ',num2str(round(J(3,4),3)),' ',num2str(round(J(3,5),3))],' ',[num2str(round(J(4,1),3)),' ',num2str(round(J(4,2),3)),' ',num2str(round(J(4,3),3)),' ',num2str(round(J(4,4),3)),' ',num2str(round(J(4,5),3))],' ',[num2str(round(J(5,1),3)),' ',num2str(round(J(5,2),3)),' ',num2str(round(J(5,3),3)),' ',num2str(round(J(5,4),3)),' ',num2str(round(J(5,5),3))],' ',[num2str(round(J(6,1),3)),' ',num2str(round(J(6,2),3)),' ',num2str(round(J(6,3),3)),' ',num2str(round(J(6,4),3)),' ',num2str(round(J(6,5),3))]};
            
            app.VelEfector1EditField.Value=ForwVel(1);
            app.VelEfector1EditField_2.Value=ForwVel(2);
            app.VelEfector1EditField_3.Value=ForwVel(3);
            app.VelEfector1EditField_4.Value=ForwVel(4);
            app.VelEfector1EditField_5.Value=ForwVel(5);
            app.VelEfector1EditField_6.Value=ForwVel(6);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ControlDirectoTab = uitab(app.TabGroup);
            app.ControlDirectoTab.Title = 'Control Directo';
            app.EditField_6Label = uilabel(app.ControlDirectoTab);
            app.EditField_6Label.Text = 'θ1';
            app.EditField_t1 = uieditfield(app.ControlDirectoTab, 'numeric');
            app.EditField_7Label = uilabel(app.ControlDirectoTab);
            app.EditField_7Label.Text = 'θ2';
            app.EditField_t2 = uieditfield(app.ControlDirectoTab, 'numeric');
            app.EditField_8Label = uilabel(app.ControlDirectoTab);
            app.EditField_8Label.Text = 'θ3';
            app.EditField_t3 = uieditfield(app.ControlDirectoTab, 'numeric');
            app.EditField_9Label = uilabel(app.ControlDirectoTab);
            app.EditField_9Label.Text = 'θ5';
            app.EditField_t5 = uieditfield(app.ControlDirectoTab, 'numeric');
            app.EditField_10Label = uilabel(app.ControlDirectoTab);
            app.EditField_10Label.Text = 'θ6';
            app.EditField_t6 = uieditfield(app.ControlDirectoTab, 'numeric');
            app.MatrizButton = uibutton(app.ControlDirectoTab, 'push');
            app.MatrizButton.ButtonPushedFcn = createCallbackFcn(app, @MatrizButtonPushed, true);
            app.MatrizButton.Text = 'Matriz';
            app.MoverButton = uibutton(app.ControlDirectoTab, 'push');
            app.MoverButton.ButtonPushedFcn = createCallbackFcn(app, @MoverButtonPushed, true);
            app.MoverButton.Text = 'Mover';
            app.MatrizDirectaLabel = uilabel(app.ControlDirectoTab);
            app.MatrizDirectaLabel.Text = 'Matriz Directa';
            app.MatrizDirectaResultadoLabel = uilabel(app.ControlDirectoTab);
            app.MatrizDirectaResultadoLabel.Text = 'Matriz Directa Resultado';
            app.ControlInversoTab = uitab(app.TabGroup);
            app.ControlInversoTab.Title = 'Control Inverso';
            app.XEditFieldLabel = uilabel(app.ControlInversoTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ControlInversoTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.ControlInversoTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ControlInversoTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ControlInversoTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ControlInversoTab, 'numeric');
            app.AEditFieldLabel = uilabel(app.ControlInversoTab);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.ControlInversoTab, 'numeric');
            app.BEditFieldLabel = uilabel(app.ControlInversoTab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.ControlInversoTab, 'numeric');
            app.MatrizButton_2 = uibutton(app.ControlInversoTab, 'push');
            app.MatrizButton_2.ButtonPushedFcn = createCallbackFcn(app, @MatrizButton_2Pushed, true);
            app.MatrizButton_2.Text = 'Matriz ';
            app.MoverButton_2 = uibutton(app.ControlInversoTab, 'push');
            app.MoverButton_2.ButtonPushedFcn = createCallbackFcn(app, @MoverButton_2Pushed, true);
            app.MoverButton_2.Text = 'Mover';
            app.MatrizInversaLabel = uilabel(app.ControlInversoTab);
            app.MatrizInversaLabel.Text = 'Matriz Inversa';
            app.VelocidadDirectaTab = uitab(app.TabGroup);
            app.VelocidadDirectaTab.Title = 'Velocidad Directa';
            app.VelEfector1EditField = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField.Editable = 'off';
            app.VelEfector1EditField_2 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField_2.Editable = 'off';
            app.VelEfector1EditField_3 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField_3.Editable = 'off';
            app.VelEfector1EditField_4 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField_4.Editable = 'off';
            app.VelEfector1EditField_5 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField_5.Editable = 'off';
            app.VelocidadEfectorLabel = uilabel(app.VelocidadDirectaTab);
            app.VelocidadEfectorLabel.Text = 'Velocidad Efector';
            app.MatrizJacobianaLabel = uilabel(app.VelocidadDirectaTab);
            app.MatrizJacobianaLabel.Text = 'Matriz Jacobiana';
            app.JacobianaLabel = uilabel(app.VelocidadDirectaTab);
            app.JacobianaLabel.Text = 'Jacobiana';
            app.AnguloVelEditField = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.AnguloVelEditField_2 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.AnguloVelEditField_3 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.AnguloVelEditField_4 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.AnguloVelEditField_5 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.AnguloLabel = uilabel(app.VelocidadDirectaTab);
            app.AnguloLabel.Text = 'Angulo';
            app.VelocidadAngularEditField = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelocidadAngularEditField_2 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelocidadAngularEditField_3 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelocidadAngularEditField_4 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelocidadAngularEditField_5 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.GenerarMatrizButton = uibutton(app.VelocidadDirectaTab, 'push');
            app.GenerarMatrizButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarMatrizButtonPushed, true);
            app.GenerarMatrizButton.Text = 'Generar Matriz';
            app.VelocidadAngularLabel = uilabel(app.VelocidadDirectaTab);
            app.VelocidadAngularLabel.Text = 'Velocidad Angular ';
            app.VelEfector1EditField_6 = uieditfield(app.VelocidadDirectaTab, 'numeric');
            app.VelEfector1EditField_6.Editable = 'off';
            app.VLabel = uilabel(app.VelocidadDirectaTab);
            app.VLabel.Text = 'V';
            app.WLabel = uilabel(app.VelocidadDirectaTab);
            app.WLabel.Text = 'W';
            app.VelocidadInversaTab = uitab(app.TabGroup);
            app.VelocidadInversaTab.Title = 'Velocidad Inversa';
            app.TrayectoriaTab = uitab(app.TabGroup);
            app.TrayectoriaTab.Title = 'Trayectoria';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimulacionRobot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end