classdef Straigth_Line < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Straigth_lineUIFigure    matlab.ui.Figure
        XEditFieldLabel          matlab.ui.control.Label
        X_                       matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        Y_                       matlab.ui.control.NumericEditField
        Punto1Label              matlab.ui.control.Label
        Punto2Label              matlab.ui.control.Label
        XEditFieldLabel_2        matlab.ui.control.Label
        X__2                     matlab.ui.control.NumericEditField
        YEditFieldLabel_2        matlab.ui.control.Label
        Y__2                     matlab.ui.control.NumericEditField
        StraightTrajectoryLabel  matlab.ui.control.Label
        ZEditFieldLabel          matlab.ui.control.Label
        Z_                       matlab.ui.control.NumericEditField
        SimulateButton_2         matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
            P0=[0,0,0,0,0];
            
            x1 =round(app.X_.Value);
            y1 =round(app.Y_.Value);
            
            x2 =round(app.X__2.Value);
            y2 =round(app.Y__2.Value);
            
            z =round(app.Z_.Value);
            
            alpha =174;
            beta  =179;
            
            [theta1_1,theta2_1,theta3_1,theta5_1,theta6_1]=inverseken(x1,y1,z,alpha,beta);
            
            
            theta1_1=round(theta1_1);
            theta2_1=round(theta2_1);
            theta3_1=round(theta3_1);
            theta5_1=round(theta5_1);
            theta6_1=round(theta6_1);
            
            P1=[theta1_1,theta2_1,theta3_1,theta5_1,theta6_1]
            
            [theta1_2,theta2_2,theta3_2,theta5_2,theta6_2]=inverseken(x2,y2,z,alpha,beta);
            
            
            theta1_2=round(theta1_2);
            theta2_2=round(theta2_2);
            theta3_2=round(theta3_2);
            theta5_2=round(theta5_2);
            theta6_2=round(theta6_2);
            
            P2=[theta1_2,theta2_2,theta3_2,theta5_2,theta6_2]
             
            test=[round(app.X_.Value,0),0,0,0,0];
             
             send_instruction(P0,P1);


             send_instruction(P1,P2);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Straigth_lineUIFigure = uifigure;
            app.Straigth_lineUIFigure.Name = 'Straigth_line';
            app.XEditFieldLabel = uilabel(app.Straigth_lineUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.X_ = uieditfield(app.Straigth_lineUIFigure, 'numeric');
            app.X_.ValueDisplayFormat = '%.2f';
            app.X_.Value = 76.8;
            app.YEditFieldLabel = uilabel(app.Straigth_lineUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.Y_ = uieditfield(app.Straigth_lineUIFigure, 'numeric');
            app.Y_.ValueDisplayFormat = '%.2f';
            app.Y_.Value = 14.29;
            app.Punto1Label = uilabel(app.Straigth_lineUIFigure);
            app.Punto1Label.Text = 'Punto 1';
            app.Punto2Label = uilabel(app.Straigth_lineUIFigure);
            app.Punto2Label.Text = 'Punto 2';
            app.XEditFieldLabel_2 = uilabel(app.Straigth_lineUIFigure);
            app.XEditFieldLabel_2.Text = 'X';
            app.X__2 = uieditfield(app.Straigth_lineUIFigure, 'numeric');
            app.X__2.ValueDisplayFormat = '%.2f';
            app.X__2.Value = 100;
            app.YEditFieldLabel_2 = uilabel(app.Straigth_lineUIFigure);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y__2 = uieditfield(app.Straigth_lineUIFigure, 'numeric');
            app.Y__2.ValueDisplayFormat = '%.2f';
            app.Y__2.Value = 150;
            app.StraightTrajectoryLabel = uilabel(app.Straigth_lineUIFigure);
            app.StraightTrajectoryLabel.Text = 'Straight Trajectory';
            app.ZEditFieldLabel = uilabel(app.Straigth_lineUIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z_ = uieditfield(app.Straigth_lineUIFigure, 'numeric');
            app.Z_.ValueDisplayFormat = '%.2f';
            app.Z_.Value = 400;
            app.SimulateButton_2 = uibutton(app.Straigth_lineUIFigure, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Straigth_Line

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Straigth_lineUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Straigth_lineUIFigure)
        end
    end
end